classdef CV_Zurich_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CapacitanceVoltageCVMFIAZurichTab  matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        MeasurementParametersPanel      matlab.ui.container.Panel
        XmappingEditField               matlab.ui.control.NumericEditField
        XmappingEditFieldLabel          matlab.ui.control.Label
        LoopCountEditField              matlab.ui.control.NumericEditField
        LoopCountEditFieldLabel         matlab.ui.control.Label
        Sweep_SamplecountEditField      matlab.ui.control.NumericEditField
        Sweep_SamplecountEditFieldLabel  matlab.ui.control.Label
        SweepModeScanEditField          matlab.ui.control.NumericEditField
        SweepModeScanEditFieldLabel     matlab.ui.control.Label
        StopVoltageEditField            matlab.ui.control.NumericEditField
        StopVoltageEditFieldLabel       matlab.ui.control.Label
        StartVoltageEditField           matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel      matlab.ui.control.Label
        DeviceSettingsPanel             matlab.ui.container.Panel
        imps_freqEditField              matlab.ui.control.NumericEditField
        imps_freqEditFieldLabel         matlab.ui.control.Label
        imps_amplitudeEditField         matlab.ui.control.NumericEditField
        imps_amplitudeEditFieldLabel    matlab.ui.control.Label
        imps_channelEditField           matlab.ui.control.NumericEditField
        imps_channelEditFieldLabel      matlab.ui.control.Label
        osc_cEditField                  matlab.ui.control.NumericEditField
        osc_cEditFieldLabel             matlab.ui.control.Label
        osc_freqEditField               matlab.ui.control.NumericEditField
        osc_freqEditFieldLabel          matlab.ui.control.Label
        demod_tcEditField               matlab.ui.control.NumericEditField
        demod_tcEditFieldLabel          matlab.ui.control.Label
        demod_enableEditField           matlab.ui.control.NumericEditField
        demod_enableEditFieldLabel      matlab.ui.control.Label
        demod_harmonicEditField         matlab.ui.control.NumericEditField
        demod_harmonicEditFieldLabel    matlab.ui.control.Label
        demod_rateEditField             matlab.ui.control.NumericEditField
        demod_rateEditFieldLabel        matlab.ui.control.Label
        demod_cEditField                matlab.ui.control.NumericEditField
        demod_cEditFieldLabel           matlab.ui.control.Label
        demod_orderEditField            matlab.ui.control.NumericEditField
        demod_orderEditFieldLabel       matlab.ui.control.Label
        demod_phaseshiftEditField       matlab.ui.control.NumericEditField
        demod_phaseshiftEditFieldLabel  matlab.ui.control.Label
        sigout_mixer_enableEditField    matlab.ui.control.NumericEditField
        sigout_mixer_enableEditFieldLabel  matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel         matlab.ui.control.Label
        sigout_rangeEditField           matlab.ui.control.NumericEditField
        sigout_rangeEditFieldLabel      matlab.ui.control.Label
        sigout_onEditField              matlab.ui.control.NumericEditField
        sigout_onEditFieldLabel         matlab.ui.control.Label
        input_rangeEditField            matlab.ui.control.NumericEditField
        input_rangeEditFieldLabel       matlab.ui.control.Label
        input_acEditField               matlab.ui.control.NumericEditField
        input_acEditFieldLabel          matlab.ui.control.Label
        out_cEditField                  matlab.ui.control.NumericEditField
        out_cEditFieldLabel             matlab.ui.control.Label
        input_imp50EditField            matlab.ui.control.NumericEditField
        input_imp50EditFieldLabel       matlab.ui.control.Label
        in_cEditField                   matlab.ui.control.NumericEditField
        in_cEditFieldLabel              matlab.ui.control.Label
        MFIAPropertiesPanel             matlab.ui.container.Panel
        Discovery_ID_Textarea           matlab.ui.control.TextArea
        DefaultFolderPanel              matlab.ui.container.Panel
        SelectFolderButton              matlab.ui.control.Button
        DefaultFolderEditField          matlab.ui.control.EditField
        ConnectionStatusPanel           matlab.ui.container.Panel
        MFIAZurichInstrumentsLamp       matlab.ui.control.Lamp
        MFIAZurichInstrumentsLampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        CVZurichInstrumentsMFIALabel    matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        DelaySTextArea                  matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        MeasurementWindowLabel          matlab.ui.control.Label
        DelaySTextAreaLabel             matlab.ui.control.Label
        CalculatedPointsTextArea        matlab.ui.control.TextArea
        SweepPointsTextArea             matlab.ui.control.TextArea
        StepTextArea                    matlab.ui.control.TextArea
        CalculatedPointsTextArea_2Label  matlab.ui.control.Label
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        CalculatedPointsLabel_2         matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        CV_Plot_Linear                  matlab.ui.control.UIAxes
        CV_Plot_Logarithmic             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        device_id = 'dev5478';
        device;
        props;
        connectionStatusMFIA;
        branches;
        out_mixer_c;
        stopExperiment;

        data;
        sheet1;
        sheet2;
        settings;
        measurement_table;
        measurement_var_names;
        consttableData;
        defaultFolder;
            
        i;
        voltage_source;
        capacitance_measure;
   
        in_c;
        out_c;
        input_imp50;
        input_ac;
        input_range;
        sigout_on;
        sigout_range;
        amplitude;
        sigout_mixer_enable;
        demod_phaseshift;
        demod_order;
        demod_c;
        demod_rate;
        demod_harmonic;
        demod_enable;
        demod_tc;
        osc_c;
        osc_freq;
        imps_channel;
        imps_amplitude;
        imps_freq;

        StartVoltage;
        StopVoltage; 
        sweep_mode_scan;
        sweep_samplecount;
        loopcount;
        xmapping;
    end
 
    methods (Access = public)
        function connectDeviceZurich(app)
            if strcmp(app.connectionStatusMFIA,'Connected')
                clear ziDAQ;
                app.connectionStatusMFIA='Not Connected';
            end
            try
                [app.device, app.props] = ziCreateAPISession(app.device_id, 6);
                ziDAQ('connect','127.0.0.1', 8006, 6);
                ziDAQ('connectDevice', app.device_id, 'PCIe');
                app.MFIAZurichInstrumentsLamp.Color='g';
                app.connectionStatusMFIA='Connected';
            catch
                app.MFIAZurichInstrumentsLamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('Connection Failed!');
                app.connectionStatusMFIA='Not Connected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
            defaultFallback = fullfile(userpath,'MyAppData');
            app.DefaultFolderEditField.Value = getpref('MyApp','DataFolder', defaultFallback);
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization:
            format longE
            app.defaultFolder = app.DefaultFolderEditField.Value;
            app.stopExperiment = false;      
            app.connectDeviceZurich();
            ziDisableEverything(app.device_id);

            if ~strcmp(app.connectionStatusMFIA,'Connected')
                uialert(app.UIFigure,'Cannot start the experiment because the device_id is not connected','Connection Error')
                clear;
                return;
            end

            app.IndicatorLamp.Color='y';
            app.StateoftheExperimentTextArea.Value=('Experiment started: initializing parameters');
            app.StartExperimentButton.Enable="off";
            cla(app.CV_Plot_Linear)
            cla(app.CV_Plot_Logarithmic)
            app.MeasurementTable.Data=[];
            app.MeasurementTable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.ConstantValuesTabel.Data=[];
            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;
            
            app.i = 1;
            app.voltage_source = zeros(app.i,1);
            app.capacitance_measure =zeros(app.i,1);

            app.in_c = app.in_cEditField.Value;
            app.out_c = app.out_cEditField.Value;
            app.input_imp50 = app.input_imp50EditField.Value;
            app.input_ac = app.input_acEditField.Value;
            app.input_range = app.input_rangeEditField.Value;
            app.sigout_on = app.sigout_onEditField.Value;
            app.sigout_range = app.sigout_rangeEditField.Value;
            app.amplitude = app.amplitudeEditField.Value;
            app.sigout_mixer_enable = app.sigout_mixer_enableEditField.Value;
            app.demod_phaseshift = app.demod_phaseshiftEditField.Value;
            app.demod_order = app.demod_orderEditField.Value;
            app.demod_c = app.demod_cEditField.Value;
            app.demod_rate = app.demod_rateEditField.Value;
            app.demod_harmonic = app.demod_harmonicEditField.Value;
            app.demod_enable = app.demod_enableEditField.Value;
            app.demod_tc = app.demod_tcEditField.Value;
            app.osc_c = app.osc_cEditField.Value;
            app.osc_freq = app.osc_freqEditField.Value;
            
            app.StartVoltage=app.StartVoltageEditField.Value;
            app.StopVoltage=app.StopVoltageEditField.Value;
            app.sweep_mode_scan = app.SweepModeScanEditField.Value;
            app.sweep_samplecount = app.Sweep_SamplecountEditField.Value;
            app.loopcount = app.LoopCountEditField.Value;
            app.xmapping = app.XmappingEditField.Value;
            app.imps_channel = app.imps_channelEditField.Value;
            app.imps_amplitude = app.imps_amplitudeEditField.Value;
            app.imps_freq = app.imps_freqEditField.Value;
            
            %% Parameters:
            app.sheet1 ='Settings';
            app.sheet2 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.additionalComments = [];

            json_str = jsonencode(ziDAQ('discoveryGet', app.device_id), 'PrettyPrint', true);
            app.branches = ziDAQ('listNodes', ['/' app.device_id], 0);
            app.Discovery_ID_Textarea.Value = [splitlines(json_str); {'--- Node List ---'}; app.branches(:)];
            app.out_mixer_c = str2double((ziGetDefaultSigoutMixerChannel(app.props, app.out_c)));

            %% Device Settings:
            ziDAQ('setInt',    sprintf('/%s/sigins/%d/imp50',          app.device_id,  app.in_c),  app.input_imp50);
            ziDAQ('setInt',    sprintf('/%s/sigins/%d/ac',             app.device_id,  app.in_c),  app.input_ac);
            ziDAQ('setDouble', sprintf('/%s/sigins/%d/range',          app.device_id,  app.in_c),  app.input_range);
            ziDAQ('setInt',    sprintf('/%s/sigouts/%d/on',            app.device_id,  app.out_c), app.sigout_on);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/range',         app.device_id,  app.out_c), app.sigout_range);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/amplitudes/*',  app.device_id,  app.out_c), 0);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/amplitudes/%d', app.device_id,  app.out_c, app.out_mixer_c), app.amplitude);
            ziDAQ('setDouble', sprintf('/%s/sigouts/%d/enables/%d',    app.device_id,  app.out_c, app.out_mixer_c), app.sigout_mixer_enable);
            ziDAQ('setDouble', sprintf('/%s/demods/*/phaseshift',      app.device_id), app.demod_phaseshift);
            ziDAQ('setInt',    sprintf('/%s/demods/*/order',           app.device_id), app.demod_order);
            ziDAQ('setDouble', sprintf('/%s/demods/%d/rate',           app.device_id,  app.demod_c), app.demod_rate);
            ziDAQ('setInt',    sprintf('/%s/demods/%d/harmonic',       app.device_id,  app.demod_c), app.demod_harmonic);
            ziDAQ('setInt',    sprintf('/%s/demods/%d/enable',         app.device_id,  app.demod_c), app.demod_enable); 
            ziDAQ('setDouble', sprintf('/%s/demods/*/timeconstant',    app.device_id), app.demod_tc);
            ziDAQ('setInt',    sprintf('/%s/demods/*/adcselect',       app.device_id), app.in_c);
            ziDAQ('setInt',    sprintf('/%s/demods/*/oscselect',       app.device_id), app.osc_c);
            ziDAQ('setDouble', sprintf('/%s/oscs/%d/freq',             app.device_id,  app.osc_c), app.osc_freq);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/enable',           app.device_id, app.imps_channel), 1);
            ziDAQ('setInt',    sprintf('/%s/imps/%d/output/on',        app.device_id, app.imps_channel), 1);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/freq',             app.device_id, app.imps_channel), app.imps_freq);
            ziDAQ('setDouble', sprintf('/%s/imps/%d/output/amplitude', app.device_id, app.imps_channel), app.imps_amplitude);    
            ziDAQ('sync'); 

            %% Measurements
            exp = ziDAQ('sweep');
            ziDAQ('set', exp, 'device', app.device_id);
            ziDAQ('set', exp, 'gridnode', sprintf('sigouts/%d/offset', app.out_c));
            ziDAQ('set', exp, 'start', app.StartVoltage);
            ziDAQ('set', exp, 'stop', app.StopVoltage);
            ziDAQ('set', exp, 'samplecount', app.sweep_samplecount);
            ziDAQ('set', exp, 'loopcount', app.loopcount);
            ziDAQ('set', exp, 'xmapping', app.xmapping);
            ziDAQ('set', exp, 'scan', 1);
            ziDAQ('set', exp, 'settling/time', 0);
            ziDAQ('set', exp, 'settling/inaccuracy', 0.001);
            ziDAQ('set', exp, 'averaging/tc', 50);
            ziDAQ('set', exp, 'averaging/sample', 100);
            ziDAQ('set', exp, 'bandwidthcontrol', 2);
            ziDAQ('set', exp, 'bandwidthoverlap', 0);
            ziDAQ('subscribe', exp, sprintf('/%s/imps/%d/sample', app.device_id, app.imps_channel));
            ziDAQ('execute', exp);

while ~ziDAQ('finished', exp)
    pause(0.05);
end
tmp = ziDAQ('read', exp);
ziDAQ('unsubscribe', exp, sprintf('/%s/imps/%d/sample', app.device_id, app.imps_channel));
ziDAQ('clear', exp);
sample = tmp.(app.device_id).imps(app.imps_channel+1).sample{1}
bias = sample.bias(:);
Cvals = sample.biaspwr(:);
app.voltage_source = bias;
app.capacitance_measure = Cvals;
MeasurementtableData = [(1:numel(bias))', app.voltage_source(:), app.capacitance_measure(:)];
app.MeasurementTable.ColumnFormat = {'numeric','numeric','numeric'};
app.MeasurementTable.Data = MeasurementtableData;
app.StateoftheExperimentTextArea.Value = 'IMPS sweep finished';
% 
%             MeasurementtableData = [(1:app.i-1), app.voltage_source(1:app.i-1), app.capacitance_measure(1:app.i-1)];
%             app.MeasurementTable.Data = MeasurementtableData;

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end

            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V', app.StartVoltage ;
            'Stop Voltage, V', app.StopVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Step, V', app.Step ;
            '', '' ;
            'Dual Sweep', app.Dualsweep ;
            'Repeat', app.Repeat ;
            'Sweep Points', app.Sweeppoints ;
            'Calculated Points', app.Calculatedpoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Voltage', 'Capacitance'};
                app.measurement_table = table(app.voltage_source, app.capacitance_measure ,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end

            app.StartExperimentButton.Enable="on";
    
            %% Finishing the Experiment
            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.MFIAZurichInstrumentsLamp.Color='w';
            app.StartExperimentButton.Enable="on";
            clear
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.MFIAZurichInstrumentsLamp.Color='w';
            app.StateoftheExperimentTextArea.Value=('The Experiment has been stopped, Save the data!');

            app.stopExperiment = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Start Voltage, V', app.StartVoltage ;
            'Stop Voltage, V', app.StopVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Step, V', app.Step ;
            '', '' ;
            'Dual Sweep', app.Dualsweep ;
            'Repeat', app.Repeat ;
            'Sweep Points', app.Sweeppoints ;
            'Calculated Points', app.Calculatedpoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Voltage', 'Current'};
                app.measurement_table = table(app.voltage_source, app.capacitance_measure ,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
        prevFolder = app.DefaultFolderEditField.Value;
        selected = uigetdir(prevFolder, 'Select Folder');
        if selected ~= 0
            setpref('MyApp','DataFolder', selected);
            app.DefaultFolderEditField.Value = selected;
        end
        figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CapacitanceVoltageCVMFIAZurichTab = uitab(app.TabGroup2);
            app.CapacitanceVoltageCVMFIAZurichTab.Title = 'Capacitance-Voltage (C-V, MFIA Zurich)';
            app.TabGroup = uitabgroup(app.CapacitanceVoltageCVMFIAZurichTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CVZurichInstrumentsMFIALabel = uilabel(app.SettingsTab);
            app.CVZurichInstrumentsMFIALabel.Text = 'C-V, Zurich Instruments MFIA';
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.MFIAZurichInstrumentsLampLabel = uilabel(app.ConnectionStatusPanel);
            app.MFIAZurichInstrumentsLampLabel.Text = 'MFIA Zurich Instruments';
            app.MFIAZurichInstrumentsLamp = uilamp(app.ConnectionStatusPanel);
            app.DefaultFolderPanel = uipanel(app.SettingsTab);
            app.DefaultFolderPanel.Title = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.DefaultFolderPanel, 'text');
            app.SelectFolderButton = uibutton(app.DefaultFolderPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.MFIAPropertiesPanel = uipanel(app.SettingsTab);
            app.MFIAPropertiesPanel.Title = 'MFIA Properties';
            app.Discovery_ID_Textarea = uitextarea(app.MFIAPropertiesPanel);
            app.Discovery_ID_Textarea.Editable = 'off';
            app.DeviceSettingsPanel = uipanel(app.SettingsTab);
            app.DeviceSettingsPanel.Title = 'Device Settings';
            app.in_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.in_cEditFieldLabel.Text = 'in_c';
            app.in_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_imp50EditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_imp50EditFieldLabel.Text = 'input_imp50';
            app.input_imp50EditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_imp50EditField.Value = 1;
            app.out_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.out_cEditFieldLabel.Text = 'out_c';
            app.out_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_acEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_acEditFieldLabel.Text = 'input_ac';
            app.input_acEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_acEditField.Value = 1;
            app.input_rangeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.input_rangeEditFieldLabel.Text = 'input_range';
            app.input_rangeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.input_rangeEditField.Value = 2;
            app.sigout_onEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_onEditFieldLabel.Text = 'sigout_on';
            app.sigout_onEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_onEditField.Value = 1;
            app.sigout_rangeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_rangeEditFieldLabel.Text = 'sigout_range';
            app.sigout_rangeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_rangeEditField.Value = 1;
            app.amplitudeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.amplitudeEditField.Value = 0.1;
            app.sigout_mixer_enableEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.sigout_mixer_enableEditFieldLabel.Text = 'sigout_mixer_enable';
            app.sigout_mixer_enableEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.sigout_mixer_enableEditField.Value = 1;
            app.demod_phaseshiftEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_phaseshiftEditFieldLabel.Text = 'demod_phaseshift';
            app.demod_phaseshiftEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_orderEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_orderEditFieldLabel.Text = 'demod_order';
            app.demod_orderEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_orderEditField.Value = 4;
            app.demod_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_cEditFieldLabel.Text = 'demod_c';
            app.demod_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_rateEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_rateEditFieldLabel.Text = 'demod_rate';
            app.demod_rateEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_rateEditField.Value = 13000;
            app.demod_harmonicEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_harmonicEditFieldLabel.Text = 'demod_harmonic';
            app.demod_harmonicEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_harmonicEditField.Value = 1;
            app.demod_enableEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_enableEditFieldLabel.Text = 'demod_enable';
            app.demod_enableEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_enableEditField.Value = 1;
            app.demod_tcEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.demod_tcEditFieldLabel.Text = 'demod_tc';
            app.demod_tcEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.demod_tcEditField.Value = 0.007;
            app.osc_freqEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.osc_freqEditFieldLabel.Text = 'osc_freq';
            app.osc_freqEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.osc_freqEditField.Value = 3000000;
            app.osc_cEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.osc_cEditFieldLabel.Text = 'osc_c';
            app.osc_cEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_channelEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_channelEditFieldLabel.Text = 'imps_channel';
            app.imps_channelEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_amplitudeEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_amplitudeEditFieldLabel.Text = 'imps_amplitude';
            app.imps_amplitudeEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_amplitudeEditField.Value = 0.01;
            app.imps_freqEditFieldLabel = uilabel(app.DeviceSettingsPanel);
            app.imps_freqEditFieldLabel.Text = 'imps_freq';
            app.imps_freqEditField = uieditfield(app.DeviceSettingsPanel, 'numeric');
            app.imps_freqEditField.Value = 1000;
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.StartVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StartVoltageEditField.Value = -1;
            app.StopVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopVoltageEditFieldLabel.Text = 'Stop Voltage';
            app.StopVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopVoltageEditField.Value = 1;
            app.SweepModeScanEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.SweepModeScanEditFieldLabel.Text = 'Sweep Mode/Scan';
            app.SweepModeScanEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.SweepModeScanEditField.Value = 1;
            app.Sweep_SamplecountEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.Sweep_SamplecountEditFieldLabel.Text = 'Sweep_Samplecount';
            app.Sweep_SamplecountEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.Sweep_SamplecountEditField.Value = 100;
            app.LoopCountEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.LoopCountEditFieldLabel.Text = 'Loop Count';
            app.LoopCountEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.LoopCountEditField.Value = 1;
            app.XmappingEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.XmappingEditFieldLabel.Text = 'Xmapping';
            app.XmappingEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.XmappingEditField.Value = 1;
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Voltage [V]'; 'Capacitance [F]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'NPLC'};
            app.ConstantValuesTabel.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.CV_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Logarithmic, 'Logarithmic C-V, MFIA')
            xlabel(app.CV_Plot_Logarithmic, 'Voltage [V]')
            ylabel(app.CV_Plot_Logarithmic, 'Logarithmic Capacitance [F]')
            zlabel(app.CV_Plot_Logarithmic, 'Z')
            app.CV_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.CV_Plot_Linear, 'Linear C-V, MFIA')
            xlabel(app.CV_Plot_Linear, 'Voltage [V]')
            ylabel(app.CV_Plot_Linear, 'Linear Capacitance [F]')
            zlabel(app.CV_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.VoltageTextArea = uitextarea(app.MeasurementsPanel);
            app.VoltageTextArea.Editable = 'off';
            app.VoltsLabel = uilabel(app.MeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.CalculatedPointsLabel_2 = uilabel(app.ConstantsPanel);
            app.CalculatedPointsLabel_2.Text = 'Step';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.CalculatedPointsTextArea_2Label = uilabel(app.ConstantsPanel);
            app.CalculatedPointsTextArea_2Label.Text = 'Calculated Points';
            app.StepTextArea = uitextarea(app.ConstantsPanel);
            app.StepTextArea.Editable = 'off';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.CalculatedPointsTextArea = uitextarea(app.ConstantsPanel);
            app.CalculatedPointsTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CapacitanceVoltageCVMFIAZurichTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CapacitanceVoltageCVMFIAZurichTab);
            app.StopExperimentButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CapacitanceVoltageCVMFIAZurichTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_Zurich_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end