classdef It_Keithley_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentVoltageItKeithleyTab     matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        DefaultFolderEditField          matlab.ui.control.EditField
        DefaultFolderEditFieldLabel     matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        Panel_sample_info               matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        ConnectionStatusPanel           matlab.ui.container.Panel
        KEITHLEYSourceMeter2450Lamp     matlab.ui.control.Lamp
        KEITHLEYSourceMeter2450LampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        CommonSettingsPanel             matlab.ui.container.Panel
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        SourcetoMeasureDelaySEditField  matlab.ui.control.NumericEditField
        SourcetoMeasureDelaySEditFieldLabel  matlab.ui.control.Label
        SourceSweepPointsEditField      matlab.ui.control.NumericEditField
        SourceSweepPointsLabel          matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        MeasurementParametersPanel      matlab.ui.container.Panel
        SenseListBox                    matlab.ui.control.ListBox
        InputTerminalsListBox           matlab.ui.control.ListBox
        OutputOffListBox                matlab.ui.control.ListBox
        SenseListBoxLabel               matlab.ui.control.Label
        InputTerminalsListBoxLabel      matlab.ui.control.Label
        OutputOffListBoxLabel           matlab.ui.control.Label
        MinimumRangeListBox             matlab.ui.control.ListBox
        CurrentRangeListBox             matlab.ui.control.ListBox
        VoltageRangeListBox             matlab.ui.control.ListBox
        MinimumRangeListBoxLabel        matlab.ui.control.Label
        CurrentRangeListBoxLabel        matlab.ui.control.Label
        VoltageRangeListBoxLabel        matlab.ui.control.Label
        AutoZeroSwitch                  matlab.ui.control.Switch
        AutoZeroSwitchLabel             matlab.ui.control.Label
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentLimitEditField           matlab.ui.control.NumericEditField
        CurrentLimitEditFieldLabel      matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        LevelVoltageEditField           matlab.ui.control.NumericEditField
        LevelVoltageEditFieldLabel      matlab.ui.control.Label
        ItKeithleyModeBiasLabel         matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesTabel             matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        MeasurementTable                matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        ConstantsPanel                  matlab.ui.container.Panel
        MeasureCountTextArea            matlab.ui.control.TextArea
        MeasureCountTextAreaLabel       matlab.ui.control.Label
        DelaySTextArea                  matlab.ui.control.TextArea
        DelaySTextAreaLabel             matlab.ui.control.Label
        VoltageVTextArea                matlab.ui.control.TextArea
        VoltageVTextAreaLabel           matlab.ui.control.Label
        SweepPointsTextArea             matlab.ui.control.TextArea
        SweepPointsTextAreaLabel        matlab.ui.control.Label
        MeasureWindowSTextArea          matlab.ui.control.TextArea
        MeasurementWindowLabel          matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        SLabel                          matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        VoltageLabel_2                  matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        It_Plot_Linear                  matlab.ui.control.UIAxes
        It_Plot_Logarithmic             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;
        
        data;
        sheet1;
        sheet2;
        settings;
        measurement_table;
        measurement_var_names;
        consttableData;
        defaultFolder;
            
        i;
        time;
        voltage_source;
        current_measure;

        KLYSM2450;
        connectionStatusKeithley;
        freq;
        aftervoltage;
 
        LevelVoltage;
        Voltagerange;
        Currentlimit;

        Currentrange;
        Minimumrange
        Autozero;
        Inputterminals;
        Outputoff;
        Sense;

        Sweeppoints;
        CurrentRange;
        SourcetoMeasureDelay;
        measurement_window;
        NPLC;
        Measurecount;

    end

    methods (Access = public)
        function connectDeviceKeithley(app)
            if strcmp(app.connectionStatusKeithley,'Connected')
                delete(app.KLYSM2450)
                app.connectionStatusKeithley='Not Connected';
            end
            try
                app.KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
                app.KEITHLEYSourceMeter2450Lamp.Color='g';
                app.connectionStatusKeithley='Connected';
            catch
                app.KEITHLEYSourceMeter2450Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The Phase: The Connection to the device has failed!');
                app.connectionStatusKeithley='Not Connected';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
            defaultFallback = fullfile(userpath,'MyAppData');
            app.DefaultFolderEditField.Value = getpref('MyApp','DataFolder', defaultFallback);
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization:
            app.defaultFolder = app.DefaultFolderEditField.Value;
            format longE
            app.connectDeviceKeithley();
            if ~strcmp(app.connectionStatusKeithley,'Connected')
                uialert(app.UIFigure,'Cannot start the experiment because the device is not connected','Connection Error')
                clear IV_Keithley_1;
                return;
            end
            
            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Experiment has started, resetting and initializing parameters');
            app.StartExperimentButton.Enable="off";

            cla(app.It_Plot_Linear)
            cla(app.It_Plot_Logarithmic)
            app.MeasurementTable.Data=[];
            app.ConstantValuesTabel.Data=[];
            
            app.i = 1;
            app.time=zeros(app.i,1);
            app.voltage_source=zeros(app.i,1);
            app.current_measure =zeros(app.i,1);
            

            app.LevelVoltage=app.LevelVoltageEditField.Value;
            app.Voltagerange=app.VoltageRangeListBox.Value;
            app.Currentlimit=app.CurrentLimitEditField.Value;

            app.Currentrange=app.CurrentRangeListBox.Value;
            app.Minimumrange=app.MinimumRangeListBox.Value;
            app.Autozero=app.AutoZeroSwitch.Value;
            app.Inputterminals=app.InputTerminalsListBox.Value;
            app.Outputoff=app.OutputOffListBox.Value;
            app.Sense=app.SenseListBox.Value;
 
            app.Sweeppoints=app.SourceSweepPointsEditField.Value;
            app.CurrentRange=app.CurrentRangeListBox.Value;
            app.SourcetoMeasureDelay=app.SourcetoMeasureDelaySEditField.Value;
            app.NPLC=app.NPLCEditField.Value;

            app.Measurecount=app.MeasurecountEditField.Value;
            app.aftervoltage=app.VoltageaftermeasureSwitch.Value;
            
            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;
            
            %% Saving parameters
            if exist('data.xlsx','file')==2
                delete('data.xlsx')
            end
            app.data='data.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.freq=str2double(writeread(app.KLYSM2450,'print(frequency)'));
            app.measurement_window=app.NPLC/app.freq;
            app.consttableData=[app.freq, app.NPLC];
            app.ConstantValuesTabel.ColumnFormat={'numeric'};
            app.ConstantValuesTabel.Data=app.consttableData; 
            app.VoltageVTextArea.Value=num2str(app.LevelVoltage);
            app.SweepPointsTextArea.Value=num2str(app.Sweeppoints);
            app.DelaySTextArea.Value=num2str(app.SourcetoMeasureDelay);
            app.MeasureWindowSTextArea.Value=num2str(app.measurement_window);
            app.MeasureCountTextArea.Value=num2str(app.Measurecount);
            app.additionalComments = [];
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Level Voltage, V', app.LevelVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Sweep Points', app.Sweeppoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };
            app.measurement_var_names = {'Time', 'Current', 'Voltage'};
            app.measurement_table = table(app.time,app.current_measure,app.voltage_source,'VariableNames', app.measurement_var_names);
            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet2);    

            %% Device Settings:
            writeline(app.KLYSM2450,'*RST')
            pause(0.1)
            %Voltage is the SOURCE function, Current is measurement:
            writeline(app.KLYSM2450,'smu.measure.func = smu.FUNC_DC_CURRENT')
            writeline(app.KLYSM2450,'smu.source.func = smu.FUNC_DC_VOLTAGE')
            writeline(app.KLYSM2450,'frequency=localnode.linefreq')
            %Measure Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.measure.terminals = smu.TERMINALS_REAR')
            writeline(app.KLYSM2450,'smu.measure.sense = smu.SENSE_2WIRE')
            writeline(app.KLYSM2450,'smu.measure.autorange = smu.ON')
            writeline(app.KLYSM2450, strcat('smu.measure.count = ',string(app.Measurecount)))
            writeline(app.KLYSM2450, strcat('smu.measure.nplc=', string(app.NPLC)))  
            % Source Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.source.highc = smu.OFF')
            writeline(app.KLYSM2450,'smu.source.autorange = smu.ON')
            writeline(app.KLYSM2450,'smu.source.readback = smu.ON')
            writeline(app.KLYSM2450,'smu.source.output = smu.ON')
            writeline(app.KLYSM2450,'smu.source.offmode = smu.OFFMODE_NORMAL')
            writeline(app.KLYSM2450, strcat('smu.source.ilimit.level = ', string(app.Currentlimit)))

            %% Measurements
            writeline(app.KLYSM2450,'beeper.beep(0.35, 1500); delay(0.35) ; beeper.beep(0.35, 1500)')
            app.StateoftheExperimentTextArea.Value=('The Phase: Measurements have started');
            app.IndicatorLamp.Color='r';
            writeline(app.KLYSM2450,'Voltage_Current_Buffer = buffer.make(1000),buffer.STYLE_WRITABLE_FULL')
            writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')

            %--- Time Sweep: Start -> Stop ---
            voltage = app.LevelVoltage;
            writeline(app.KLYSM2450, strcat('smu.source.level = ',string(voltage)))
            timestart=tic;
            for k = 1:app.Sweeppoints
                app.StateoftheExperimentTextArea.Value = sprintf('Experiment in progress, Measurement point number: %d', app.i);
                % Set the source voltage for the current Sweep Points and read  
                pause(app.SourcetoMeasureDelay);
                writeline(app.KLYSM2450, 'Voltage_Current_Buffer.clear()')
                writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
                app.voltage_source (app.i,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                app.current_measure(app.i,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));
                app.time(app.i,1)=toc(timestart);
                MeasurementtableData=[app.i, app.time(end), app.current_measure(end), app.voltage_source(end)];
                app.MeasurementTable.ColumnFormat={'numeric'};
                app.MeasurementTable.Data=[app.MeasurementTable.Data; MeasurementtableData];
                app.TimeTextArea.Value=num2str(app.time(app.i,1));
                app.CurrentTextArea.Value=num2str(app.current_measure(app.i,1));
                plot(app.It_Plot_Linear,app.time,app.current_measure)
                xlabel(app.It_Plot_Linear,'Time, S')
                ylabel(app.It_Plot_Linear,'Current Linear, A')
                title(app.It_Plot_Linear,'Linear I-t Characteristics')
                semilogy(app.It_Plot_Logarithmic, app.time, abs(app.current_measure))
                xlabel(app.It_Plot_Logarithmic,'Time, S')
                ylabel(app.It_Plot_Logarithmic,'Current Logarithmic, A')
                title(app.It_Plot_Logarithmic,'Logarithmic I-t Characteristics')
                app.measurement_table = table(app.time, app.current_measure, app.voltage_source,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet2);     
                app.i = app.i + 1;
            end
            
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Level Voltage, V', app.LevelVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Sweep Points', app.Sweeppoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };
            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Time', 'Current', 'Voltage'};
                app.measurement_table = table(app.time, app.current_measure, app.voltage_source,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end

            %% Finishing the Experiment
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put output off in KEITHLEY
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.KEITHLEYSourceMeter2450Lamp.Color='w';
            app.StartExperimentButton.Enable="on";
            clc
            clearvars -global
            clear IV_Keithley_1
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.KEITHLEYSourceMeter2450Lamp.Color='w';

            app.StateoftheExperimentTextArea.Value=('The Experiment has been stopped, Save the data!');
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Level Voltage, V', app.LevelVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Sweep Points', app.Sweeppoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };
            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Time', 'Current', 'Voltage'};
                app.measurement_table = table(app.time, app.current_measure, app.voltage_source,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end

            waitfor(app.StartExperimentButton)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {
            'Time', '' ;
            'Date', app.dateString ;
            'Clock', app.timeString ;
            '', '' ;
            'Sample Info', app.SampleInfo ;
            'Comments', app.Comments ;
            'Additional Comments', app.additionalComments ;
            '', '' ;
            'Measurement Parameters', '' ;
            'Level Voltage, V', app.LevelVoltage ;
            'Current Limit, A', app.Currentlimit ;
            'Sweep Points', app.Sweeppoints ;
            '', '' ;
            'Source to Measure Delay, S', app.SourcetoMeasureDelay ;
            'NPLC', app.NPLC ;
            'Measure Window, S', app.measurement_window ;
            'Measurecount', app.Measurecount ;
            'Frequency, Hz', app.freq ;
            '', '' ;
            'Input Terminals', app.Inputterminals ;
            'Output Off', app.Outputoff ;
            'Sense', app.Sense ;
            '', '' ;
            'Voltage Range', app.Voltagerange ;
            'Current Range', app.CurrentRange ;
            'Minimum Range', app.Minimumrange ;
            'Auto Zero', app.Autozero ;
            };
            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                app.measurement_var_names = {'Time', 'Current', 'Voltage'};
                app.measurement_table = table(app.time, app.current_measure, app.voltage_source,'VariableNames', app.measurement_var_names);
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);   
                figure(app.UIFigure)
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
        prevFolder = app.DefaultFolderEditField.Value;
        selected = uigetdir(prevFolder, 'Select Folder');
        if selected ~= 0
            setpref('MyApp','DataFolder', selected);
            app.DefaultFolderEditField.Value = selected;
        end
        figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CurrentVoltageItKeithleyTab = uitab(app.TabGroup2);
            app.CurrentVoltageItKeithleyTab.Title = 'Current-Voltage (I-t, Keithley)';
            app.TabGroup = uitabgroup(app.CurrentVoltageItKeithleyTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ItKeithleyModeBiasLabel = uilabel(app.SettingsTab);
            app.ItKeithleyModeBiasLabel.Text = 'I-t / Keithley, Mode: Bias';
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.LevelVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.LevelVoltageEditFieldLabel.Text = 'Level Voltage';
            app.LevelVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.LevelVoltageEditField.Value = -1;
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_6.Text = '[Volts]';
            app.CurrentLimitEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentLimitEditFieldLabel.Text = 'Current Limit';
            app.CurrentLimitEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.CurrentLimitEditField.Value = 0.1;
            app.AmpsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.AmpsLabel_2.Text = '[Amps]';
            app.AutoZeroSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.AutoZeroSwitchLabel.Text = 'Auto Zero';
            app.AutoZeroSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.VoltageRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.VoltageRangeListBoxLabel.Text = 'Voltage Range';
            app.CurrentRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentRangeListBoxLabel.Text = 'Current Range';
            app.MinimumRangeListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.MinimumRangeListBoxLabel.Text = 'Minimum Range';
            app.VoltageRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.VoltageRangeListBox.Items = {'Auto'};
            app.VoltageRangeListBox.Value = 'Auto';
            app.CurrentRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.CurrentRangeListBox.Items = {'Auto'};
            app.CurrentRangeListBox.Value = 'Auto';
            app.MinimumRangeListBox = uilistbox(app.MeasurementParametersPanel);
            app.MinimumRangeListBox.Items = {'10 nA'};
            app.MinimumRangeListBox.Value = '10 nA';
            app.OutputOffListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.OutputOffListBoxLabel.Text = 'Output Off';
            app.InputTerminalsListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.InputTerminalsListBoxLabel.Text = 'Input Terminals';
            app.SenseListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.SenseListBoxLabel.Text = 'Sense';
            app.OutputOffListBox = uilistbox(app.MeasurementParametersPanel);
            app.OutputOffListBox.Items = {'Normal'};
            app.OutputOffListBox.Value = 'Normal';
            app.InputTerminalsListBox = uilistbox(app.MeasurementParametersPanel);
            app.InputTerminalsListBox.Items = {'Rear', 'Front'};
            app.InputTerminalsListBox.Value = 'Rear';
            app.SenseListBox = uilistbox(app.MeasurementParametersPanel);
            app.SenseListBox.Items = {'2-Wire', '4-Wire'};
            app.SenseListBox.Value = '2-Wire';
            app.CommonSettingsPanel = uipanel(app.SettingsTab);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.NPLCEditField_2Label = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.NPLCEditField.Value = 0.1;
            app.NPLCEditField_2Label_2 = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.SourceSweepPointsLabel = uilabel(app.CommonSettingsPanel);
            app.SourceSweepPointsLabel.Text = 'Source / Sweep Points';
            app.SourceSweepPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourceSweepPointsEditField.Limits = [2 Inf];
            app.SourceSweepPointsEditField.Value = 21;
            app.SourcetoMeasureDelaySEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.SourcetoMeasureDelaySEditFieldLabel.Text = {'Source to Measure Delay'; '[S]'};
            app.SourcetoMeasureDelaySEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourcetoMeasureDelaySEditField.Value = 0.1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.CommonSettingsPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.CommonSettingsPanel, 'slider');
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.KEITHLEYSourceMeter2450LampLabel = uilabel(app.ConnectionStatusPanel);
            app.KEITHLEYSourceMeter2450LampLabel.Text = 'KEITHLEY SourceMeter 2450';
            app.KEITHLEYSourceMeter2450Lamp = uilamp(app.ConnectionStatusPanel);
            app.Panel_sample_info = uipanel(app.SettingsTab);
            app.Panel_sample_info.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_sample_info);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_sample_info, 'text');
            app.SampleInfoEditField.Tooltip = {''};
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.SelectFolderButton = uibutton(app.SettingsTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'SelectFolder';
            app.DefaultFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultFolderEditFieldLabel.Text = 'Default Folder';
            app.DefaultFolderEditField = uieditfield(app.SettingsTab, 'text');
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.MeasurementTable = uitable(app.Tables);
            app.MeasurementTable.ColumnName = {'Number'; 'Time [S]'; 'Current [A]'; 'Voltage [V]'};
            app.MeasurementTable.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.ConstantValuesTabel = uitable(app.Tables);
            app.ConstantValuesTabel.ColumnName = {'Frequency'; 'NPLC'};
            app.ConstantValuesTabel.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.It_Plot_Logarithmic = uiaxes(app.GraphsTab);
            title(app.It_Plot_Logarithmic, 'Logarithmic I-t Characteristics')
            xlabel(app.It_Plot_Logarithmic, 'Time [S]')
            ylabel(app.It_Plot_Logarithmic, 'Logarithmic Current [A]')
            zlabel(app.It_Plot_Logarithmic, 'Z')
            app.It_Plot_Linear = uiaxes(app.GraphsTab);
            title(app.It_Plot_Linear, 'Linear I-t Characteristics')
            xlabel(app.It_Plot_Linear, 'Time [S]')
            ylabel(app.It_Plot_Linear, 'Linear Current [A]')
            zlabel(app.It_Plot_Linear, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.StateoftheExperimentTextArea.Editable = 'off';
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.VoltageLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltageLabel_2.Text = 'Time';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Current';
            app.TimeTextArea = uitextarea(app.MeasurementsPanel);
            app.TimeTextArea.Editable = 'off';
            app.SLabel = uilabel(app.MeasurementsPanel);
            app.SLabel.Text = '[S]';
            app.CurrentTextArea = uitextarea(app.MeasurementsPanel);
            app.CurrentTextArea.Editable = 'off';
            app.AmpsLabel = uilabel(app.MeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.ConstantsPanel = uipanel(app.GraphsTab);
            app.ConstantsPanel.Title = 'Constants';
            app.MeasurementWindowLabel = uilabel(app.ConstantsPanel);
            app.MeasurementWindowLabel.Text = 'Measure Window, S';
            app.MeasureWindowSTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureWindowSTextArea.Editable = 'off';
            app.SweepPointsTextAreaLabel = uilabel(app.ConstantsPanel);
            app.SweepPointsTextAreaLabel.Text = 'Sweep Points';
            app.SweepPointsTextArea = uitextarea(app.ConstantsPanel);
            app.SweepPointsTextArea.Editable = 'off';
            app.VoltageVTextAreaLabel = uilabel(app.ConstantsPanel);
            app.VoltageVTextAreaLabel.Text = 'Voltage, V';
            app.VoltageVTextArea = uitextarea(app.ConstantsPanel);
            app.VoltageVTextArea.Editable = 'off';
            app.DelaySTextAreaLabel = uilabel(app.ConstantsPanel);
            app.DelaySTextAreaLabel.Text = 'Delay, S';
            app.DelaySTextArea = uitextarea(app.ConstantsPanel);
            app.DelaySTextArea.Editable = 'off';
            app.MeasureCountTextAreaLabel = uilabel(app.ConstantsPanel);
            app.MeasureCountTextAreaLabel.Text = 'Measure Count';
            app.MeasureCountTextArea = uitextarea(app.ConstantsPanel);
            app.MeasureCountTextArea.Editable = 'off';
            app.StartExperimentButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CurrentVoltageItKeithleyTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CurrentVoltageItKeithleyTab);
            app.StopExperimentButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CurrentVoltageItKeithleyTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = It_Keithley_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end